classdef SatCovSim < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SimulationDataPanel          matlab.ui.container.Panel
        MeanSatellitesTextArea       matlab.ui.control.TextArea
        MeanSatellitesTextAreaLabel  matlab.ui.control.Label
        LongitudeMinTextArea         matlab.ui.control.TextArea
        LongitudeTextArea_2Label     matlab.ui.control.Label
        LatitudeMinTextArea          matlab.ui.control.TextArea
        LatitudeTextArea_2Label      matlab.ui.control.Label
        MinSatellitesTextArea        matlab.ui.control.TextArea
        MinSatellitesTextAreaLabel   matlab.ui.control.Label
        LongitudeMaxTextArea         matlab.ui.control.TextArea
        LongitudeTextAreaLabel       matlab.ui.control.Label
        LatitudeMaxTextArea          matlab.ui.control.TextArea
        LatitudeTextAreaLabel        matlab.ui.control.Label
        MaxSatellitesTextArea        matlab.ui.control.TextArea
        MaxSatellitesTextAreaLabel   matlab.ui.control.Label
        SimulationControlsPanel      matlab.ui.container.Panel
        StatusTextArea               matlab.ui.control.TextArea
        StatusTextAreaLabel          matlab.ui.control.Label
        DateandTimeTextArea          matlab.ui.control.TextArea
        DateandTimeTextAreaLabel     matlab.ui.control.Label
        PrevButton                   matlab.ui.control.Button
        NextButton                   matlab.ui.control.Button
        ComputeCoverageButton        matlab.ui.control.Button
        SimulationOptionsPanel       matlab.ui.container.Panel
        WeatherDropDown              matlab.ui.control.DropDown
        WeatherDropDownLabel         matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        HandoverButton               matlab.ui.control.RadioButton
        CoverageButton               matlab.ui.control.RadioButton
        FrequencyTextArea            matlab.ui.control.TextArea
        FrequencyTextAreaLabel       matlab.ui.control.Label
        FrequencyGHzSlider           matlab.ui.control.Slider
        FrequencyGHzSliderLabel      matlab.ui.control.Label
        OperatorDropDown             matlab.ui.control.DropDown
        OperatorDropDownLabel        matlab.ui.control.Label
        CountryDropDown              matlab.ui.control.DropDown
        CountryDropDownLabel         matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        SatelliteScenario % Scenario that holds the satellites | SatelliteScenario object
        Satellites        % Satellites in the satellites scenario | Satellite objects array
        
        SatOperator       % Selected satellite operator | String
        Frequency         % Satellite operation frequency | uint8
        Weather           % Weather to simmulate for | string
        InterpolateData   % To interpolate data over 4 seconds | logical

        GroundPoints      % Ground points for country area estimation | single
        
        StartTime         % Sim start time | datetime
        CurrTime          % Current sim time |datetime
        StopTime          % Sim stop time | datetime
        StepSize          % Temporal step size | uint8
        
        MaxVisibleSats    % Max. visible sats for a time points | uint8
        IsVisible         % View data for each single ground point | uint8
        
        MaxNumOfSats      % Max. number of visible satellites from ground | uint8
        MinNumOfSats      % Min. number of visible satellites from ground | uint8
        MeanNumOfSats     % Mean number of visible satellites from ground | uint8
        
        CurrIdx           % Idx for current satellites | uint16
        CurrDisplay       % Used to know what data to display | uint8
        % 1 - Displaying coverage
        % 2 - Displayng handover/nhandover/dropped
        % 3 - Probability for a handover to happend in SimTime - TO DO
        Handovers
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InterpolateData = true;
            app.SatelliteScenario = satelliteScenario("AutoSimulate", false);
            app.SatelliteScenario.StartTime = datetime("now");
            app.SatelliteScenario.StopTime = app.SatelliteScenario.StartTime + minutes(90);
            app.CurrIdx = uint16(1); % Failsafe for corner case
            app.CurrDisplay = uint8(0); % No active display at startup
            app.Handovers = NaN;
            app.Weather = "clear";
        end

        % Value changed function: OperatorDropDown
        function OperatorDropDownValueChanged(app, event)
            value = app.OperatorDropDown.Value;
            if value == "Iridium"
                app.SatOperator = "iridium-NEXT";
            else
                app.SatOperator = lower(value);
            end
            getSatData(app.SatOperator);
            
            app.Satellites = loadAllSatData(app.SatOperator, app.SatelliteScenario);
            app.StartTime = app.SatelliteScenario.StartTime;
            app.StopTime = app.SatelliteScenario.StopTime;
            
            
        end

        % Value changed function: FrequencyGHzSlider
        function FrequencyGHzSliderValueChanged(app, event)
            value = app.FrequencyGHzSlider.Value;
            app.Frequency = single(value);
            app.FrequencyTextArea.Value = string(value) + " GHz";
        end

        % Value changed function: CountryDropDown
        function CountryDropDownValueChanged(app, event)
            value = app.CountryDropDown.Value;
            borders = single(getCountryBorders(value));
            p1 = [max(borders(:, 1)), min(borders(:, 2))];
            p2 = [min(borders(:, 1)), max(borders(:, 2))];
            
            myArea = single(devideArea(p1, p2, 6));
            app.GroundPoints = single(computeValidPoints(borders, myArea));
        end

        % Button pushed function: ComputeCoverageButton
        function ComputeCoverageButtonPushed(app, event)
            app.StatusTextArea.Value = "Loading...";

            [app.MaxVisibleSats, app.IsVisible] = computeCoverage(app.GroundPoints, app.Frequency, app.Satellites, app.SatelliteScenario, app.StatusTextArea, app.Weather, app.InterpolateData);
            
            app.DateandTimeTextArea.Value = string(app.StartTime);
            app.CurrTime = app.StartTime;
            app.CurrIdx = uint16(1);
            app.CurrDisplay = uint8(1);
            %app.StepSize = seconds(app.SatelliteScenario.SampleTime);
            app.StepSize = seconds(3);

            % Store max
            [val, idx] = max(app.MaxVisibleSats, [], 1);
            val = uint16(val);
            app.MaxNumOfSats = [idx;val];

            % Store min
            [val, idx] = min(app.MaxVisibleSats, [], 1);
            val = uint16(val);
            app.MinNumOfSats = [idx;val];

            % Store mean
            app.MeanNumOfSats = mean(app.MaxVisibleSats, 1);
            
            app.CoverageButton.Value = true;
            updateCoverage(app);

            app.StatusTextArea.Value = "Simulation done!";
            displayHeatmap(app.MaxVisibleSats, app.GroundPoints, app.CurrIdx, app.UIAxes, "turbo");
        end

        % Button pushed function: NextButton
        function NextButtonPushed(app, event)
            % ! Cornet case ! User tries to go beyond simtime
            if app.CurrTime + app.StepSize < app.StopTime
                app.CurrTime = app.CurrTime + app.StepSize;
                app.CurrIdx = app.CurrIdx + uint16(1);
            end
            
            switch(app.CurrDisplay)
                case 1
                    updateCoverage(app);
                case 2
                    if (app.CurrTime >= app.StopTime - 2*app.StepSize) || (app.CurrTime < app.StartTime + app.StepSize)
                        app.CurrTime = app.StartTime + app.StepSize;
                    end
                    updateHandover(app);
                case 3
                    updateProbability(app);
                otherwise
                    disp("No selection available");
            end
        end

        % Button pushed function: PrevButton
        function PrevButtonPushed(app, event)
             % ! Corner case ! User tries to go beyond simtime
            if app.CurrTime - app.StepSize > app.StartTime
                app.CurrTime = app.CurrTime - app.StepSize;
                app.CurrIdx = app.CurrIdx - uint16(1);
            end
            
            switch(app.CurrDisplay)
                case 1
                    updateCoverage(app);
                case 2
                    if (app.CurrTime >= app.StopTime - 2 * app.StepSize) || (app.CurrTime < app.StartTime + app.StepSize)
                        app.CurrTime = app.StartTime + app.StepSize;
                    end
                    updateHandover(app);
                case 3
                    updateProbability(app);
                otherwise
                    disp("No selection available");
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
            switch(selectedButton)
                case app.CoverageButton
                    app.CurrDisplay = uint8(1);
                case app.HandoverButton
                    app.CurrDisplay = uint8(2);
                    if isnan(app.Handovers)
                        app.Handovers = computeLinkStatus(app.IsVisible)';
                    end

                    if app.CurrTime >= (app.StopTime - 2 * app.StepSize) || (app.CurrTime < app.StartTime + app.StepSize)
                        app.CurrTime = app.StartTime + app.StepSize;
                    end
                case app.HProbabilityButton
                    app.CurrDisplay = uint8(3);
            end
            % Debug app.LongitudeMaxTextArea.Value = string(app.CurrDisplay); 
        end

        % Value changed function: WeatherDropDown
        function WeatherDropDownValueChanged(app, event)
            value = app.WeatherDropDown.Value;
            switch(value)
                case "Clear"
                    app.Weather = "clear";
                case "Rainy"
                    app.Weather = "rain";
                case "Thunderstorm"
                    app.Weather = "storm";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Heatmap')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimulationOptionsPanel = uipanel(app.UIFigure);
            app.SimulationOptionsPanel.Title = 'Simulation Options';
            app.CountryDropDownLabel = uilabel(app.SimulationOptionsPanel);
            app.CountryDropDownLabel.Text = 'Country';
            app.CountryDropDown = uidropdown(app.SimulationOptionsPanel);
            app.CountryDropDown.Items = {'None', 'Romania'};
            app.CountryDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryDropDownValueChanged, true);
            app.CountryDropDown.Value = 'None';
            app.OperatorDropDownLabel = uilabel(app.SimulationOptionsPanel);
            app.OperatorDropDownLabel.Text = 'Operator';
            app.OperatorDropDown = uidropdown(app.SimulationOptionsPanel);
            app.OperatorDropDown.Items = {'None', 'Iridium', 'Starlink', 'Orbcomm', 'Eutelsat', 'SES'};
            app.OperatorDropDown.ValueChangedFcn = createCallbackFcn(app, @OperatorDropDownValueChanged, true);
            app.OperatorDropDown.Value = 'None';
            app.FrequencyGHzSliderLabel = uilabel(app.SimulationOptionsPanel);
            app.FrequencyGHzSliderLabel.Text = 'Frequency (GHz)';
            app.FrequencyGHzSlider = uislider(app.SimulationOptionsPanel);
            app.FrequencyGHzSlider.Limits = [1 110];
            app.FrequencyGHzSlider.MajorTicks = [1 10 20 30 40 50 60 70 80 90 100 110];
            app.FrequencyGHzSlider.ValueChangedFcn = createCallbackFcn(app, @FrequencyGHzSliderValueChanged, true);
            app.FrequencyGHzSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.FrequencyGHzSlider.Step = 0.5;
            app.FrequencyGHzSlider.Value = 1;
            app.FrequencyTextAreaLabel = uilabel(app.SimulationOptionsPanel);
            app.FrequencyTextAreaLabel.Text = 'Frequency';
            app.FrequencyTextArea = uitextarea(app.SimulationOptionsPanel);
            app.ButtonGroup = uibuttongroup(app.SimulationOptionsPanel);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.CoverageButton = uiradiobutton(app.ButtonGroup);
            app.CoverageButton.Text = 'Coverage';
            app.CoverageButton.Value = true;
            app.HandoverButton = uiradiobutton(app.ButtonGroup);
            app.HandoverButton.Text = 'Handover';
            app.WeatherDropDownLabel = uilabel(app.SimulationOptionsPanel);
            app.WeatherDropDownLabel.Text = 'Weather';
            app.WeatherDropDown = uidropdown(app.SimulationOptionsPanel);
            app.WeatherDropDown.Items = {'Clear', 'Rainy', 'Thunderstorm'};
            app.WeatherDropDown.ValueChangedFcn = createCallbackFcn(app, @WeatherDropDownValueChanged, true);
            app.WeatherDropDown.Value = 'Clear';
            app.SimulationControlsPanel = uipanel(app.UIFigure);
            app.SimulationControlsPanel.Title = 'Simulation Controls';
            app.ComputeCoverageButton = uibutton(app.SimulationControlsPanel, 'push');
            app.ComputeCoverageButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeCoverageButtonPushed, true);
            app.ComputeCoverageButton.Text = 'Compute Coverage';
            app.NextButton = uibutton(app.SimulationControlsPanel, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.NextButton.Text = 'Next';
            app.PrevButton = uibutton(app.SimulationControlsPanel, 'push');
            app.PrevButton.ButtonPushedFcn = createCallbackFcn(app, @PrevButtonPushed, true);
            app.PrevButton.Text = 'Prev';
            app.DateandTimeTextAreaLabel = uilabel(app.SimulationControlsPanel);
            app.DateandTimeTextAreaLabel.Text = 'Date and Time';
            app.DateandTimeTextArea = uitextarea(app.SimulationControlsPanel);
            app.StatusTextAreaLabel = uilabel(app.SimulationControlsPanel);
            app.StatusTextAreaLabel.Text = 'Status';
            app.StatusTextArea = uitextarea(app.SimulationControlsPanel);
            app.SimulationDataPanel = uipanel(app.UIFigure);
            app.SimulationDataPanel.Title = 'Simulation Data';
            app.MaxSatellitesTextAreaLabel = uilabel(app.SimulationDataPanel);
            app.MaxSatellitesTextAreaLabel.Text = 'Max Satellites';
            app.MaxSatellitesTextArea = uitextarea(app.SimulationDataPanel);
            app.LatitudeTextAreaLabel = uilabel(app.SimulationDataPanel);
            app.LatitudeTextAreaLabel.Text = 'Latitude';
            app.LatitudeMaxTextArea = uitextarea(app.SimulationDataPanel);
            app.LongitudeTextAreaLabel = uilabel(app.SimulationDataPanel);
            app.LongitudeTextAreaLabel.Text = 'Longitude';
            app.LongitudeMaxTextArea = uitextarea(app.SimulationDataPanel);
            app.MinSatellitesTextAreaLabel = uilabel(app.SimulationDataPanel);
            app.MinSatellitesTextAreaLabel.Text = 'Min Satellites';
            app.MinSatellitesTextArea = uitextarea(app.SimulationDataPanel);
            app.LatitudeTextArea_2Label = uilabel(app.SimulationDataPanel);
            app.LatitudeTextArea_2Label.Text = 'Latitude';
            app.LatitudeMinTextArea = uitextarea(app.SimulationDataPanel);
            app.LongitudeTextArea_2Label = uilabel(app.SimulationDataPanel);
            app.LongitudeTextArea_2Label.Text = 'Longitude';
            app.LongitudeMinTextArea = uitextarea(app.SimulationDataPanel);
            app.MeanSatellitesTextAreaLabel = uilabel(app.SimulationDataPanel);
            app.MeanSatellitesTextAreaLabel.Text = 'Mean Satellites';
            app.MeanSatellitesTextArea = uitextarea(app.SimulationDataPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SatCovSim

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end